classdef Final_Project_Feet_Classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        min_valueEditField             matlab.ui.control.NumericEditField
        min_valueEditFieldLabel        matlab.ui.control.Label
        rotateButton                   matlab.ui.control.Button
        FoottypeButtonGroup            matlab.ui.container.ButtonGroup
        RightButton                    matlab.ui.control.RadioButton
        LeftButton                     matlab.ui.control.RadioButton
        NextButton                     matlab.ui.control.Button
        PreviousButton                 matlab.ui.control.Button
        ExpertmodeButton               matlab.ui.control.StateButton
        UselastpathCheckBox            matlab.ui.control.CheckBox
        FileEditField                  matlab.ui.control.EditField
        FileEditFieldLabel             matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        RGBDimensionsButtonGroup       matlab.ui.container.ButtonGroup
        AllButton                      matlab.ui.control.RadioButton
        BlueButton                     matlab.ui.control.RadioButton
        GreenButton                    matlab.ui.control.RadioButton
        RedButton                      matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        DataProcessingTab              matlab.ui.container.Tab
        ContactDetectionPanel          matlab.ui.container.Panel
        testButton                     matlab.ui.control.Button
        BandwidthEditField             matlab.ui.control.NumericEditField
        BandwidthEditFieldLabel        matlab.ui.control.Label
        ProcessButton                  matlab.ui.control.Button
        RGBDimensionsButtonGroup_2     matlab.ui.container.ButtonGroup
        BlueButton_2                   matlab.ui.control.RadioButton
        GreenButton_2                  matlab.ui.control.RadioButton
        RedButton_2                    matlab.ui.control.RadioButton
        UIAxes2                        matlab.ui.control.UIAxes
        BackgroundDetectionPanel       matlab.ui.container.Panel
        fitparabolaButton              matlab.ui.control.Button
        findheelButton                 matlab.ui.control.Button
        findtoeButton                  matlab.ui.control.Button
        Detection_window_EditField     matlab.ui.control.NumericEditField
        DetectionWindowEditFieldLabel  matlab.ui.control.Label
        Iterations_EditField           matlab.ui.control.NumericEditField
        alliterationsLabel             matlab.ui.control.Label
        Neighbour_threshold_Slider     matlab.ui.control.Slider
        NeighbourthresholdLabel        matlab.ui.control.Label
        Refine_detection_Button        matlab.ui.control.Button
        Detect_foot_pixel_Button       matlab.ui.control.Button
        Threshold_Slider               matlab.ui.control.Slider
        ThresholdSliderLabel           matlab.ui.control.Label
        LandmarkIdentificationTab      matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

%% to do
% create image with colors
% images with noise
% improve single/double peak --> modus peak calculations
% second toe bei left and right foot
% detect if left or right foot
%

    properties (Access = private)
        image_dispalyed
        file
        app_settings
        working_directory
        version
        image_display_counter
    end

    methods (Access = private)
        %% differentiates between image and background
        function image_cut_out = background_removal(app, image, color, threshold)
            %color white = 1
            %color black = 0
            SizeP = size(app.file.double);
            for i_row = 1 : SizeP(1) % rows
                for i_col = 1 : SizeP(2) % columns
                    if color
                        if image(i_row,i_col,1)>=(threshold/100) && image(i_row,i_col,2)>=(threshold/100) && image(i_row,i_col,3)>=(threshold/100)
                            image_cut_out(i_row,i_col) = 0;
                        else
                            image_cut_out(i_row,i_col) = 1;
                        end
                    else
                        if image(i_row,i_col,1)<= 1-(threshold/100) && image(i_row,i_col,2)<= 1-(threshold/100) && image(i_row,i_col,3)<= 1-(threshold/100)
                            image_cut_out(i_row,i_col) = 0;
                            image_cut_out(i_row,i_col) = 0;
                        else
                            image_cut_out(i_row,i_col) = 1;
                        end
                    end
                end
            end
        end
        %% refines image/background seperation by checking neighbours
        function output_logical = Neighborhood_refinement(app, image_logical)
            Size_log = size(image_logical);
            output_logical = nan(Size_log);
            Raster = ones(app.Detection_window_EditField.Value); % Raster of detection
            Neighborhood = conv2(image_logical, Raster, 'same'); % determine the sum of the neighbouring pixels
            cut_off = (app.Neighbour_threshold_Slider.Value .* numel(Raster) ) ./ 100;

            for row = 1:size(image_logical,1)
                for col = 1:size(image_logical,2)
                    for dim = 1:size(image_logical,3)
                        if Neighborhood(row,col,dim) >= cut_off
                            output_logical(row,col,dim) = 1;
                        elseif Neighborhood(row,col,dim) < cut_off
                            output_logical(row,col,dim) = 0;
                        end
                    end
                end
            end

        end

        %% function changes mouse cursor
        function hourglass_cursor(app,value)
            if value
                set(app.UIFigure,'Pointer', 'watch');
                drawnow;
            else
                set(app.UIFigure,'Pointer', 'arrow');
                drawnow;
            end
        end

        %% safes images of all processing steps and determines current image for axes
        function version_control(app,image,title)
            if isempty(app.version{1,1})
                index = 1;
            else
                index = (length(app.version(:,1))) +1;
            end
            app.version{index,1} = image;
            app.version{index,2} = size(image,3);
            app.version{index,3} = title;
            app.image_dispalyed = image;
        end

        %% shows current image in axes
        % move is 1 if user wants to show next image and -1 if previous
        function show_image(app,move)
            if nargin == 1
                index = size(app.version,1);
                app.image_display_counter = size(app.version,1);
                %set temp image to selected dimension
            else
                if (app.image_display_counter + move) <= size(app.version,1) && (app.image_display_counter + move) >= 1
                    app.image_display_counter = app.image_display_counter + move;
                    index = app.image_display_counter;
                else
                    index = app.image_display_counter;
                end
            end
            current_image = app.version{index,1};
            image_dims = app.version{index,2};
            current_title = app.version{index,3};
            temp_image = current_image;
            if image_dims > 1
                if app.RedButton.Value
                    temp_image(:,:,1) = current_image(:,:,1);
                    temp_image(:,:,2:3) = 0;
                elseif app.BlueButton.Value
                    temp_image(:,:,3) = current_image(:,:,3);
                    temp_image(:,:,1:2) = 0;
                elseif app.GreenButton.Value
                    temp_image(:,:,2) = current_image(:,:,2);
                    temp_image(:,:,1:2:3) = 0;
                end
            end
            % plot image
            imshow(temp_image,"Parent",app.UIAxes);
            title(app.UIAxes, current_title);
            axis(app.UIAxes,'off');
        end


        %% classify pixels based on trainings group
        function classify_image(app)
            cla(app.UIAxes2,"reset");
            if app.RedButton_2.Value
                intensity_RGB  = app.file.image_DW(:,:,1);
            elseif app.GreenButton_2.Value
                intensity_RGB  = app.file.image_DW(:,:,2);
            elseif app.BlueButton_2.Value
                intensity_RGB  = app.file.image_DW(:,:,3);
            end
            Intensity_counter = 0 : 255; % vector for the intensity values from 1 to 255
            n_intensity  = nan(size(Intensity_counter));
            for ctr = Intensity_counter
                if ctr == 0
                    n_intensity(ctr+1) = 0;
                else
                    n_intensity(ctr+1) = length(find(intensity_RGB == ctr)); % search for eacht intensity value in the matrix and see how oten it is present

                end
            end

            % aufsummieren der werte des intensity vector / vec-1 verkürzt dann
            Intensity_sum = nan(1,( size(n_intensity,2)-1));
            for i = 1:size(n_intensity,2)-1
                if i == 1
                    Intensity_sum(i) = sum(n_intensity(i) + n_intensity(i+1));
                else
                    Intensity_sum(i) = sum(Intensity_sum(i-1)+n_intensity(i+1));
                end
            end
            % modus
            modus = Intensity_sum(end)/2;
            mod_ind = find(Intensity_sum>=modus,1);% ind
            % max
            [~,max1_idx] = max(n_intensity(1:mod_ind));
            direction_max2 = n_intensity(mod_ind:end); %direction to search for second peak right side of modus
            [max2,~] = max(direction_max2);
            max2_idx = find(n_intensity == max2);
            % min
            [~,min_idx] = min(n_intensity(max1_idx:max2_idx));
            min_idx = min_idx + max1_idx -1;
            % plot
            plot(app.UIAxes2,n_intensity,'k');
            hold(app.UIAxes2, "on");
            xline(app.UIAxes2,mod_ind,'y');
            xline(app.UIAxes2,max1_idx,'r');
            xline(app.UIAxes2,max2_idx,'r');
            xline(app.UIAxes2,min_idx,'b');
            legend(app.UIAxes2,'Intensity', 'Modus', 'Maximum1', 'Maximum2', 'Minimum');
            
            % determine and plot bands
            conf_width = app.BandwidthEditField.Value;
            first_peak_conf_width = conf_width * (min_idx - max1_idx);
            second_peak_conf_width = conf_width * (max2_idx - min_idx);
            line(app.UIAxes2,[max1_idx + first_peak_conf_width, max1_idx + first_peak_conf_width],[0 3000],'Color','g' );
            line(app.UIAxes2,[max1_idx - first_peak_conf_width, max1_idx - first_peak_conf_width],[0 3000], 'Color', 'g' );
            line(app.UIAxes2,[max2_idx + second_peak_conf_width, max2_idx + second_peak_conf_width],[0 3000],'Color','g' );
            line(app.UIAxes2,[max2_idx - second_peak_conf_width, max2_idx - second_peak_conf_width],[0 3000], 'Color', 'g' );

            % create trainings group
            % group 1 = no contact, group 2 = contact
            green_foot_mask = app.file.image_DW(:,:,2);
            red_foot_mask = app.file.image_DW(:,:,1);
            blue_foot_mask = app.file.image_DW(:,:,3);
            trainings_counter = 0;
            for i = 1:(size(green_foot_mask, 1) * size(green_foot_mask,2))
                if green_foot_mask(i) < ((max1_idx + first_peak_conf_width)) && green_foot_mask(i) > ((max1_idx - first_peak_conf_width))
                    disp('1');
                    trainings_counter = trainings_counter +1;
                    group(trainings_counter) = 1;
                    training(trainings_counter,1) = red_foot_mask(i);
                    training(trainings_counter,2) = green_foot_mask(i);
                    training(trainings_counter,3) = blue_foot_mask(i);
                elseif  green_foot_mask(i) > ((max2_idx - second_peak_conf_width)) && green_foot_mask(i) < ((max2_idx + second_peak_conf_width))
                    disp('1');
                    trainings_counter = trainings_counter +1;
                    group(trainings_counter) = 0;
                    training(trainings_counter,1) = red_foot_mask(i);
                    training(trainings_counter,2) = green_foot_mask(i);
                    training(trainings_counter,3) = blue_foot_mask(i);
                end
                sample(i,1) = red_foot_mask(i);
                sample(i,2) = green_foot_mask(i);
                sample(i,3) = blue_foot_mask(i);
            end
            assignin("base", "training",training);
            assignin("base", "sample",sample)
            assignin("base", "group",group)
            sample = cast(sample,'double');
            training = cast(training,'double');
            class = classify(sample,training,group');
            % change back all vectors to matrices
            end_indices = [0,size(app.file.image_DW, 1):(size(app.file.image_DW, 1)):length(class)];
            for column_counter = 1:(length(end_indices) -1)
                resulting_foot(:,column_counter) = class((end_indices(column_counter)+1):end_indices(column_counter +1));
            end
            resulting_foot_unit8 = cast(resulting_foot,'uint8');
            foot_trained = resulting_foot_unit8 .* app.file.image_DW;
            version_control(app,foot_trained,'Trained foot');
            show_image(app);
        end

        %% function that rotates images
        function image = remove_borders(app,image)
            rows_to_delete = [];
            cols_to_delete = [];
            for row = 1:size(image,1)
                if sum(image(row,:,:),"all") == 0
                    rows_to_delete = [rows_to_delete,row];
                end
            end
            image(rows_to_delete,:,:) = [];
            for col = 1:size(image,2)
                if sum(image(:,col,:),"all") == 0
                    cols_to_delete = [cols_to_delete,col];
                end
            end
            image(:,cols_to_delete,:) = [];
        end

    %% function that determines borderlines of image
    function [x,y,x_left,y_left, x_right, y_right] = vertical_borderlines(app,binary_image)
            size_image = size(binary_image,1);
            y_right = nan((size_image), 1);
            x_right = nan((size_image), 1);
            y_left = nan((size_image), 1);
            x_left = nan((size_image), 1);
            for row = 1:size_image
                left_point_col = find(binary_image(row, :), 1, 'first');
                right_point_col = find(binary_image(row, :), 1, 'last');
                if ~isempty(left_point_col)
                    y_left(row) = size_image + 1 - row;
                    x_left(row) = left_point_col;
                end
                if ~isempty(right_point_col)
                    y_right(end - row) = size_image + 1 - row;
                    x_right(end - row) = right_point_col;
                end
            end
            x_both = [x_left, x_right];
            y_both = [y_left, y_right];
            x = x_both(~isnan(x_both));
            y = y_both(~isnan(y_both));
            x_right = x_right(~isnan(x_right));
            y_right = y_right(~isnan(y_right));
            x_left = x_left(~isnan(x_left));
            y_left = y_left(~isnan(y_left));
    end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialise variables
            app.file = struct();
            app.AllButton.Value = 1;

            % go to directory of app
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            app.working_directory = filepath;
            cd(filepath);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            cd(app.working_directory);
            cla(app.UIAxes,"reset");
            cla(app.UIAxes2,"reset");
            % reset image versions and counter for displaying images
            app.version = cell(1,3);
            app.image_display_counter = 0;

            if app.UselastpathCheckBox.Value == false
                seleceted_path = uigetdir;
                save("App_Settings","seleceted_path");
            else
                if exist(fullfile(app.working_directory,"App_Settings.mat"),"file")
                    app.app_settings = load("App_Settings.mat","seleceted_path");
                    seleceted_path = cell2mat(struct2cell(app.app_settings));
                else
                    seleceted_path = uigetdir;
                    save("App_Settings","seleceted_path");
                end
            end
            cd(seleceted_path);
            filename = uigetfile('.jpg');
            % get app back in foreground
            drawnow;
            figure(app.UIFigure)
            % safe image in struct and version control
            app.file.raw = imread(filename,'jpg');
            app.FileEditField.Value = filename;
            % transform into double
            app.file.double = cast(app.file.raw,'double') / 255;
            version_control(app,app.file.double,'Raw');
            % check wether backgroundcolor is white or black
            boarderpoint = app.file.double(1,1) + app.file.double(1,end ) + app.file.double(end,1) + app.file.double(end,end);
            if  boarderpoint >=2
                app.file.backgroundcolor = 1; % white background
            else
                app.file.backgroundcolor = 0; % black background
            end
            show_image(app)

            %%
            %%
            %%
            %% determine if left or right foot
            count_right_side = 0;
            count_left_side = 0;
            first_pixel = [];
            % determine if file name contains letter r or l
            if contains(app.FileEditField.Value, 'r')|| contains(app.FileEditField.Value, 'R')
               count_right_side = count_right_side +1;
            end
            if contains(app.FileEditField.Value, 'l') || contains(app.FileEditField.Value, 'L')
               count_left_side = count_left_side +1;
            end
            % determine edges of foot
            edges_image = edge(rgb2gray(app.file.double),"canny");
            % search first pixel of foot edge
            for row = 1:size(edges_image,1)
                for columns = 1:size(edges_image,2)
                    if edges_image(row, columns)
                        first_pixel = columns;
                        break
                    end
                end
                if ~isempty(first_pixel)
                    break
                end
            end
            
            % if first
            if first_pixel < (size(edges_image,2) /2)
                count_right_side = count_right_side +1;
            else
                count_left_side = count_left_side +1;
            end
            
            if count_right_side > count_left_side
                app.RightButton.Value = 1;
            elseif count_right_side < count_left_side
                app.LeftButton.Value = 1;
            else
                uialert(app.UIFigure,'Side dectection not clear. Check selection and correct manually, if necessary.','Side detection not conclusive'); 
            end

            imshow(edges_image, "Parent",app.UIAxes);
        end

        % Selection changed function: RGBDimensionsButtonGroup
        function RGBDimensionsButtonGroupSelectionChanged(app, event)
            show_image(app);
        end

        % Button pushed function: Detect_foot_pixel_Button
        function Detect_foot_pixel_ButtonPushed(app, event)
            hourglass_cursor(app,1);
            app.file.cut_out_logical = app.background_removal(app.file.double, app.file.backgroundcolor, app.Threshold_Slider.Value);
            app.file.cut_out = app.file.cut_out_logical .* app.file.double;
            version_control(app,app.file.cut_out,'Background removed');
            show_image(app);
            hourglass_cursor(app,0);
        end

        % Button pushed function: Refine_detection_Button
        function Refine_detection_ButtonPushed(app, event)
            hourglass_cursor(app,1);
            image_logical = app.file.cut_out_logical;
            for i = 1:app.Iterations_EditField.Value
                image_logical = Neighborhood_refinement(app, image_logical);
            end
            app.file.logical = image_logical;
            app.file.logical_uint8 = cast(image_logical, 'uint8');
            app.file.image_DW = app.file.raw .* app.file.logical_uint8;
            version_control(app,app.file.image_DW,'Neighbour refinement');
            show_image(app);
            hourglass_cursor(app,0);
            % --> subplot
        end

        % Value changed function: ExpertmodeButton
        function ExpertmodeButtonValueChanged(app, event)
            if app.ExpertmodeButton.Value
                app.Threshold_Slider.Visible = "on";
                app.ThresholdSliderLabel.Visible = "on";
                app.Detection_window_EditField.Visible = "on";
                app.DetectionWindowEditFieldLabel.Visible = "on";
                app.Neighbour_threshold_Slider.Visible = "on";
                app.NeighbourthresholdLabel.Visible = "on";
                app.Iterations_EditField.Visible = 'on';
                app.alliterationsLabel.Visible = "on";
            else
                app.Threshold_Slider.Visible = "off";
                app.ThresholdSliderLabel.Visible = "off";
                app.Detection_window_EditField.Visible = "off";
                app.DetectionWindowEditFieldLabel.Visible = "off";
                app.Neighbour_threshold_Slider.Visible = "off";
                app.NeighbourthresholdLabel.Visible = "off";
                app.Iterations_EditField.Visible = 'off';
                app.alliterationsLabel.Visible = "off";
            end
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            hourglass_cursor(app,1)
            show_image(app,1)
            hourglass_cursor(app,0);
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            hourglass_cursor(app,1)
            show_image(app,-1)
            hourglass_cursor(app,0)
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            hourglass_cursor(app,1)
            classify_image(app)
            hourglass_cursor(app,0)
        end

        % Button pushed function: testButton
        function testButtonPushed(app, event)
            hourglass_cursor(app,1)
            cla(app.UIAxes2,"reset");
            current_image = rgb2gray(app.file.image_DW);
            version_control(app,current_image,'grayscale');
            show_image(app);
            assignin("base","current_image",current_image);

            Intensity_counter = 0 : 255; % vector for the intensity values from 1 to 255
            n_intensity  = nan(size(Intensity_counter));
            for ctr = Intensity_counter
                if ctr == 0
                    n_intensity(ctr+1) = 0;
                else
                    n_intensity(ctr+1) = length(find(current_image == ctr)); % search for eacht intensity value in the matrix and see how oten it is present

                end
            end

            % aufsummieren der werte des intensity vector / vec-1 verkürzt dann
            Intensity_sum = nan(1,( size(n_intensity,2)-1));
            for i = 1:size(n_intensity,2)-1
                if i == 1
                    Intensity_sum(i) = sum(n_intensity(i) + n_intensity(i+1));
                else
                    Intensity_sum(i) = sum(Intensity_sum(i-1)+n_intensity(i+1));
                end
            end
            % modus
            modus = Intensity_sum(end)/2;
            mod_ind = find(Intensity_sum>=modus,1);% ind
            % max
            [~,max1_idx] = max(n_intensity(1:mod_ind));
            direction_max2 = n_intensity(mod_ind:end); %direction to search for second peak right side of modus
            [max2,~] = max(direction_max2);
            max2_idx = find(n_intensity == max2);
            % min
            [~,min_idx] = min(n_intensity(max1_idx:max2_idx));
            min_idx = min_idx + max1_idx -1;
            % plot
            plot(app.UIAxes2,n_intensity,'k');
            hold(app.UIAxes2, "on");
            xline(app.UIAxes2,mod_ind,'y');
            xline(app.UIAxes2,max1_idx,'r');
            xline(app.UIAxes2,max2_idx,'r');
            xline(app.UIAxes2,min_idx,'b');
            legend(app.UIAxes2,'Intensity', 'Modus', 'Maximum1', 'Maximum2', 'Minimum');
            
            % determine and plot bands
            conf_width = app.BandwidthEditField.Value;
            first_peak_conf_width = conf_width * (min_idx - max1_idx);
            second_peak_conf_width = conf_width * (max2_idx - min_idx);
            line(app.UIAxes2,[max1_idx + first_peak_conf_width, max1_idx + first_peak_conf_width],[0 3000],'Color','g' );
            line(app.UIAxes2,[max1_idx - first_peak_conf_width, max1_idx - first_peak_conf_width],[0 3000], 'Color', 'g' );
            line(app.UIAxes2,[max2_idx + second_peak_conf_width, max2_idx + second_peak_conf_width],[0 3000],'Color','g' );
            line(app.UIAxes2,[max2_idx - second_peak_conf_width, max2_idx - second_peak_conf_width],[0 3000], 'Color', 'g' );

            % create trainings group
            % group 1 = no contact, group 2 = contact
            green_foot_mask = app.file.image_DW(:,:,2);
            red_foot_mask = app.file.image_DW(:,:,1);
            blue_foot_mask = app.file.image_DW(:,:,3);
            trainings_counter = 0;
            for i = 1:(size(current_image, 1) * size(current_image,2))
                if current_image(i) < ((max1_idx + first_peak_conf_width)) && current_image(i) > ((max1_idx - first_peak_conf_width))
                    disp('1');
                    trainings_counter = trainings_counter +1;
                    group(trainings_counter) = 1;
                    training(trainings_counter,1) = red_foot_mask(i);
                    training(trainings_counter,2) = green_foot_mask(i);
                    training(trainings_counter,3) = blue_foot_mask(i);
                elseif  current_image(i) > ((max2_idx - second_peak_conf_width)) && current_image(i) < ((max2_idx + second_peak_conf_width))
                    disp('1');
                    trainings_counter = trainings_counter +1;
                    group(trainings_counter) = 0;
                    training(trainings_counter,1) = red_foot_mask(i);
                    training(trainings_counter,2) = green_foot_mask(i);
                    training(trainings_counter,3) = blue_foot_mask(i);
                end
                sample(i,1) = red_foot_mask(i);
                sample(i,2) = green_foot_mask(i);
                sample(i,3) = blue_foot_mask(i);
            end
            assignin("base", "training",training);
            assignin("base", "sample",sample)
            assignin("base", "group",group)
            sample = cast(sample,'double');
            training = cast(training,'double');
            class = classify(sample,training,group');
            % change back all vectors to matrices
            end_indices = [0,size(app.file.image_DW, 1):(size(app.file.image_DW, 1)):length(class)];
            for column_counter = 1:(length(end_indices) -1)
                resulting_foot(:,column_counter) = class((end_indices(column_counter)+1):end_indices(column_counter +1));
            end
            resulting_foot_unit8 = cast(resulting_foot,'uint8');
            foot_trained = resulting_foot_unit8 .* app.file.image_DW;
            version_control(app,foot_trained,'Trained foot');
            show_image(app);
            hourglass_cursor(app,0)
        end

        % Button pushed function: findtoeButton
        function findtoeButtonPushed(app, event)
            cla(app.UIAxes2, "reset");
            cla(app.UIAxes, "reset");
            borderline = nan(size(app.file.logical_uint8,2),1);
            for i = 1:size(app.file.logical_uint8,2)
                for j = 1:size(app.file.logical_uint8,1)
                    if app.file.logical_uint8(j,i) == 1
                        borderline(i) = size(app.file.logical_uint8,1) - j;               
                        break;
                    end
                end
            end
            plot(app.UIAxes2,borderline)
            [pks,locs] = findpeaks(borderline);
            if app.LeftButton.Value
            second_toe = [size(app.file.logical_uint8,1) - pks(end - 1),locs(end-1)];
            else
            second_toe = [size(app.file.logical_uint8,1) - pks(2),locs(2)];    
            end
            hold(app.UIAxes2,"on");
            for i = 1:length(pks)
            line(app.UIAxes2,[locs(i) locs(i)],[0 1000],'Color','g' );
            end
            app.file.image_DW(second_toe(1), second_toe(2),1) = 255;
            app.file.image_DW(second_toe(1), second_toe(2),2) = 0;    
            app.file.image_DW(second_toe(1), second_toe(2),3) = 0;
            version_control(app,app.file.image_DW,'toe');
            show_image(app);
            hold(app.UIAxes, "on");
            line(app.UIAxes,[second_toe(2) second_toe(2)],[0 200],'Color','r' );
    
        end

        % Button pushed function: findheelButton
        function findheelButtonPushed(app, event)
[rows, ~, ~] = size(app.file.image_DW);
% Get the mask where the region is solid.
binaryImage = logical(app.file.logical((rows/2):end,:));
% Fill it and take the largest blob:
binaryImage = imfill(binaryImage, 'holes');
binaryImage = bwareafilt(binaryImage, 1);
% Display the image.
imshow(binaryImage,'Parent',app.UIAxes);

% Get a new binary image where it only includes "wide" rows.
regionWidth = zeros(rows, 1);
for row = 1 : (rows/2)
  thisWidth = find(binaryImage(row, :), 1, 'last') - find(binaryImage(row, :), 1, 'first');
  if ~isempty(thisWidth)
    regionWidth(row) = thisWidth;
  end
end
% Plot widths.
figure(1)
fontSize = 12;
plot(regionWidth, 'b-', 'LineWidth', 2);
grid on;
title('Region Width vs. line down the image', 'FontSize', fontSize);
xlabel('Row', 'FontSize', fontSize);
ylabel('Width in pixels', 'FontSize', fontSize);


% % Define what "too narrow" is
% minWidth = 20;
% binaryImage(regionWidth < minWidth, :) = false; % Erase too narrow rows.
% % Display the image.
% subplot(2, 3, 4);
% imshow(binaryImage, []);
% title('Final Binary Image', 'FontSize', fontSize, 'Interpreter', 'None');
% axis('on', 'image');


% Measure elliptical properties
props = regionprops(binaryImage, 'MajorAxisLength', 'MinorAxisLength', 'Orientation', 'Centroid', 'Perimeter');
%------------------------------------------------------------------
% Create an ellipse with specified
% semi-major and semi-minor axes, center, and image size.
% From the FAQ: https://matlab.wikia.com/wiki/FAQ#How_do_I_create_an_ellipse.3F
xCenter = props.Centroid(1);
yCenter = props.Centroid(2);
xRadius = props.MinorAxisLength / 2;
yRadius = props.MajorAxisLength / 2;
% Make an angle array of about the same number of angles as there are pixels in the perimeter.
theta = linspace(0, 2*pi, ceil(props.Perimeter));
x = xRadius * cos(theta) + xCenter;
y = yRadius * sin(theta) + yCenter;
% Now we might need to rotate the coordinates slightly.  Make a rotation matrix
% Reference: https://en.wikipedia.org/wiki/Rotation_matrix
angleInDegrees = props.Orientation - 90;
rotationMatrix = [cosd(angleInDegrees), -sind(angleInDegrees); sind(angleInDegrees), cosd(angleInDegrees)];
% Now do the rotation
xy = [x', y'];
xyRotated = xy * rotationMatrix;
x = xyRotated(:, 1);
y = xyRotated(:, 2);
figure(2)
imshow(app.file.image_DW((rows/2):end,:), []);
title('Image with Fitted Ellipse', 'FontSize', fontSize, 'Interpreter', 'None');
axis('on', 'image');
hold on;
plot(x , y , 'r-', 'LineWidth', 2);
plot(xCenter, yCenter, 'r+', 'LineWidth', 2, 'MarkerSize', 13); % Put a cross at teh ellipse center.
axis('image', 'on');
grid on;
% Print results
fprintf('X Center = column #%.1f\n', xCenter);
fprintf('Y Center = row #%.1f\n', yCenter);
fprintf('Minor Axis Length = %.1f\n', xRadius);
fprintf('Major Axis Length = %.1f\n', yRadius);
fprintf('Orientation = %.1f degrees\n', angleInDegrees);
        

        end

        % Button pushed function: fitparabolaButton
        function fitparabolaButtonPushed(app, event)
            % select only bottom part of image = 1/n_parts
            n_parts = 6; 
            hourglass_cursor(app,1);
            rotation_values = -20:2:20;
            sum_values = nan(1,length(rotation_values));
            % rotate image before doing calculations
            for rotate_counter = 1:length(rotation_values)
                temp_image = imrotate(app.file.double,rotation_values(rotate_counter));
                cut_out_logical = app.background_removal(temp_image, app.file.backgroundcolor, app.Threshold_Slider.Value);
                image_logical = cut_out_logical;
                for i = 1:app.Iterations_EditField.Value
                    image_logical = Neighborhood_refinement(app, image_logical);
                end
                [rows, ~, ~] = size(image_logical);
                starting_idx = floor((n_parts - 1) * rows/n_parts);
                % Get the mask where the region is solid.
                binaryImage = logical(image_logical(starting_idx:end,:));
                % Fill it and take the largest blob:
                binaryImage = imfill(binaryImage, 'holes');
                binaryImage = bwareafilt(binaryImage, 1);
                %                 % Display the image.
                %                 imshow(binaryImage,'Parent',app.UIAxes);
                % Get vertical borederlines
                [x,y,x_left,y_left, x_right, y_right] = vertical_borderlines(app,binaryImage);
                % calculate sum of diff to mean
                for i = 1:length(x_left)
                    diff_left(i) = (floor(x_left(i) - mean(x_left))).^2;
                end
                for j = 1:length(x_left)
                    diff_right(j) = (floor(x_right(j) - mean(x_right))).^2;
                end
                left_side_diff = sum(diff_left,2);
                right_side_diff = sum(diff_right,2);

                % Plot widths.
%                 figure(rotate_counter)
%                 plot(x,y, 'o');
%                 hold on;
%                 plot(x_left,y_left, 'r');
%                 plot(x_right,y_right, 'g');
%                 grid on;
%                 p = polyfit(x,y,2);
%                 fitted_values = polyval(p,x);
%                 hold on;
%                 plot(x,fitted_values,'r');
%                 title(num2str(left_side_diff - right_side_diff));
                sum_values(rotate_counter) = left_side_diff - right_side_diff;
            end
            n=0;
            [~,idx]=min(abs(sum_values-n));
            app.min_valueEditField.Value = rotation_values(idx);
            hourglass_cursor(app,0);

        end

        % Callback function
        function rotateplusButtonPushed(app, event)

        end

        % Button pushed function: rotateButton
        function rotateButtonPushed(app, event)
            temp_img = imrotate(app.image_dispalyed,app.min_valueEditField.Value);
            rotated_foot = remove_borders(app,temp_img);
            version_control(app,rotated_foot,'rotated');
            show_image(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {582, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RGBDimensionsButtonGroup = uibuttongroup(app.LeftPanel);
            app.RGBDimensionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RGBDimensionsButtonGroupSelectionChanged, true);
            app.RGBDimensionsButtonGroup.Title = 'RGB Dimensions: ';
            app.RedButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.RedButton.Text = 'Red ';
            app.RedButton.Value = true;
            app.GreenButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.GreenButton.Text = 'Green ';
            app.BlueButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.AllButton = uiradiobutton(app.RGBDimensionsButtonGroup);
            app.AllButton.Text = 'All';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FileEditFieldLabel = uilabel(app.LeftPanel);
            app.FileEditFieldLabel.Text = {'File: '; ''};
            app.FileEditField = uieditfield(app.LeftPanel, 'text');
            app.UselastpathCheckBox = uicheckbox(app.LeftPanel);
            app.UselastpathCheckBox.Text = 'Use last path ';
            app.UselastpathCheckBox.Value = true;
            app.ExpertmodeButton = uibutton(app.LeftPanel, 'state');
            app.ExpertmodeButton.ValueChangedFcn = createCallbackFcn(app, @ExpertmodeButtonValueChanged, true);
            app.ExpertmodeButton.Text = 'Expert mode';
            app.ExpertmodeButton.Value = true;
            app.PreviousButton = uibutton(app.LeftPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FoottypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.FoottypeButtonGroup.Title = 'Foot type';
            app.LeftButton = uiradiobutton(app.FoottypeButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.FoottypeButtonGroup);
            app.RightButton.Text = 'Right';
            app.rotateButton = uibutton(app.LeftPanel, 'push');
            app.rotateButton.ButtonPushedFcn = createCallbackFcn(app, @rotateButtonPushed, true);
            app.rotateButton.Text = 'rotate';
            app.min_valueEditFieldLabel = uilabel(app.LeftPanel);
            app.min_valueEditFieldLabel.Text = 'min_value';
            app.min_valueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DataProcessingTab = uitab(app.TabGroup);
            app.DataProcessingTab.Title = 'Data Processing ';
            app.BackgroundDetectionPanel = uipanel(app.DataProcessingTab);
            app.BackgroundDetectionPanel.Title = 'Background Detection';
            app.ThresholdSliderLabel = uilabel(app.BackgroundDetectionPanel);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.Threshold_Slider = uislider(app.BackgroundDetectionPanel);
            app.Threshold_Slider.Value = 95;
            app.Detect_foot_pixel_Button = uibutton(app.BackgroundDetectionPanel, 'push');
            app.Detect_foot_pixel_Button.ButtonPushedFcn = createCallbackFcn(app, @Detect_foot_pixel_ButtonPushed, true);
            app.Detect_foot_pixel_Button.Text = 'Detect foot pixel';
            app.Refine_detection_Button = uibutton(app.BackgroundDetectionPanel, 'push');
            app.Refine_detection_Button.ButtonPushedFcn = createCallbackFcn(app, @Refine_detection_ButtonPushed, true);
            app.Refine_detection_Button.Text = 'Refine detection';
            app.NeighbourthresholdLabel = uilabel(app.BackgroundDetectionPanel);
            app.NeighbourthresholdLabel.Text = {'Neighbour '; 'threshold'};
            app.Neighbour_threshold_Slider = uislider(app.BackgroundDetectionPanel);
            app.Neighbour_threshold_Slider.Value = 75;
            app.alliterationsLabel = uilabel(app.BackgroundDetectionPanel);
            app.alliterationsLabel.Text = 'Iterations';
            app.Iterations_EditField = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.Iterations_EditField.Limits = [1 20];
            app.Iterations_EditField.Value = 4;
            app.DetectionWindowEditFieldLabel = uilabel(app.BackgroundDetectionPanel);
            app.DetectionWindowEditFieldLabel.Text = 'Detection Window';
            app.Detection_window_EditField = uieditfield(app.BackgroundDetectionPanel, 'numeric');
            app.Detection_window_EditField.Limits = [3 7];
            app.Detection_window_EditField.Value = 3;
            app.findtoeButton = uibutton(app.BackgroundDetectionPanel, 'push');
            app.findtoeButton.ButtonPushedFcn = createCallbackFcn(app, @findtoeButtonPushed, true);
            app.findtoeButton.Text = 'findtoe';
            app.findheelButton = uibutton(app.BackgroundDetectionPanel, 'push');
            app.findheelButton.ButtonPushedFcn = createCallbackFcn(app, @findheelButtonPushed, true);
            app.findheelButton.Text = 'find heel';
            app.fitparabolaButton = uibutton(app.BackgroundDetectionPanel, 'push');
            app.fitparabolaButton.ButtonPushedFcn = createCallbackFcn(app, @fitparabolaButtonPushed, true);
            app.fitparabolaButton.Text = 'fit parabola';
            app.ContactDetectionPanel = uipanel(app.DataProcessingTab);
            app.ContactDetectionPanel.Title = 'Contact Detection';
            app.UIAxes2 = uiaxes(app.ContactDetectionPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.RGBDimensionsButtonGroup_2 = uibuttongroup(app.ContactDetectionPanel);
            app.RGBDimensionsButtonGroup_2.Title = 'RGB Dimensions: ';
            app.RedButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.RedButton_2.Text = 'Red ';
            app.RedButton_2.Value = true;
            app.GreenButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.GreenButton_2.Text = 'Green ';
            app.BlueButton_2 = uiradiobutton(app.RGBDimensionsButtonGroup_2);
            app.BlueButton_2.Text = 'Blue';
            app.ProcessButton = uibutton(app.ContactDetectionPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = {'Process'; ''};
            app.BandwidthEditFieldLabel = uilabel(app.ContactDetectionPanel);
            app.BandwidthEditFieldLabel.Text = 'Band width';
            app.BandwidthEditField = uieditfield(app.ContactDetectionPanel, 'numeric');
            app.BandwidthEditField.Value = 0.5;
            app.testButton = uibutton(app.ContactDetectionPanel, 'push');
            app.testButton.ButtonPushedFcn = createCallbackFcn(app, @testButtonPushed, true);
            app.testButton.Text = 'test';
            app.LandmarkIdentificationTab = uitab(app.TabGroup);
            app.LandmarkIdentificationTab.Title = 'Landmark Identification';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project_Feet_Classification

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end